classdef WEB_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        CatlogodePlantasMenu  matlab.ui.container.Menu
        AgregarButton         matlab.ui.control.Button
        EliminarButton        matlab.ui.control.Button
        UITable               matlab.ui.control.Table
    end

    properties (Access = public)
         AgregarApp              % Dialog box app
         tabla = []    
         socket
         idPlanta
         selected
    end
    
    methods (Access = public)
        
        function sendData(app,data)
            fwrite(app.socket, data)
        end
        
        function data = receiveData(app)
            data = fgetl(app.socket);
        end
        
        function updateTable(app)
            app.sendData('getPlants')
            app.tabla = app.receiveData();
            app.tabla = replace(app.tabla,"u'","");
            app.tabla = replace(app.tabla,"'","");
            app.tabla = replace(app.tabla,"None"," ");
            app.tabla = replace(app.tabla,"[(","");
            app.tabla = replace(app.tabla,")]","");
            app.tabla = split(app.tabla,'), (');
            app.tabla = split(app.tabla,',');
            app.tabla = replace(app.tabla,"'","");
            Nombre = app.tabla(:,1);
            Sensor = app.tabla(:,2);
            Sensor = categorical(Sensor,{'1';'2'},"Protected",true);
            Descripcion = app.tabla(:,3);
            Habilitado = app.tabla(:,4);
            Habilitado = cellfun(@str2num,Habilitado);
            Habilitado = logical(Habilitado);
            app.idPlanta = app.tabla(:,5);
            app.idPlanta = cellfun(@str2num,app.idPlanta);
            app.tabla = table(Nombre,Sensor,Descripcion,Habilitado);
            app.UITable.Data = app.tabla;
        end
        
        function modifyPlant(app,indice,idPlanta,newData)
            if indice == 1
                newData = char(newData);
                data = strcat('modifyPlant',',',idPlanta,',','Nombre',',',newData);
                data = char(data);
                app.sendData(data)
            elseif indice == 2
                newData = char(newData);
                data = strcat('modifyPlant',',',idPlanta,',','Sensor',',',newData);
                data = char(data);
                app.sendData(data)
            elseif indice == 3
                newData = char(newData);
                data = strcat('modifyPlant',',',idPlanta,',','Descripcion',',',newData);
                data = char(data);
                app.sendData(data)
            else 
                newData = num2str(newData);
                data = strcat('modifyPlant',',',idPlanta,',','Habilitado',',',newData);
                data = char(data);
                app.sendData(data)
            end
            
        end
        
        function confirmClose(app, src, event)
            % Determine which dialog button the user clicked
            answer = event.SelectedOption;
            
            % Close the app if the user clicks OK
            if strcmp(answer,'OK')
                id = num2str(app.idPlanta(app.selected));
                app.deletePlant(id)
                app.receiveData()
                app.updateTable()
                app.EliminarButton.Enable = 'off';
            end
            app.EliminarButton.Enable = 'off';
        end
        
        function deletePlant(app,idPlanta)
             data = strcat('deletePlant',',',idPlanta);
             data = char(data);
             app.sendData(data)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            close all
            
            app.EliminarButton.Enable = 'off';
            app.socket = tcpip('192.168.1.2', 30000, 'NetworkRole', 'client');
            fopen(app.socket)
            
            app.updateTable()
            %app.tabla = split(app.tabla,'), (')
            
            
            %datos = textscan(app.tabla,'%s','Delimiter','), (')'
            %app.UITable.Data = ;
            
        end

        % Button pushed function: AgregarButton
        function AgregarButtonPushed(app, event)
            
            app.CatlogodePlantasMenu.Enable = 'off';
            app.AgregarButton.Enable = 'off';
            app.EliminarButton.Enable = 'off';
            app.UITable.Enable = 'off';
            
            app.AgregarApp = agregar(app);
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.socket)
            delete(app)
            
        end

        % Callback function
        function ModificarButtonPushed(app, event)
            app.tabla
            app.idPlanta
            whos
            %dato = cellstr(app.tabla)
            
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            id = num2str(app.idPlanta(indices(1)));
            app.modifyPlant(indices(2),id,newData)
            app.receiveData()
            app.updateTable()
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            app.EliminarButton.Enable = 'on';
            indices = event.Indices;
            app.selected = indices(1);
        end

        % Button pushed function: EliminarButton
        function EliminarButtonPushed(app, event)
            plant = app.tabla(app.selected,1);
            plant = table2cell(plant);
            plant = string(plant);
            msg = strcat('¿Estas seguro de eliminar',plant,'?')
            uiconfirm(app.UIFigure,msg,'Eliminar Planta','CloseFcn',@app.confirmClose);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CatlogodePlantasMenu = uimenu(app.UIFigure);
            app.CatlogodePlantasMenu.Text = 'Catálogo de Plantas';
            app.AgregarButton = uibutton(app.UIFigure, 'push');
            app.AgregarButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarButtonPushed, true);
            app.AgregarButton.Text = 'Agregar';
            app.EliminarButton = uibutton(app.UIFigure, 'push');
            app.EliminarButton.ButtonPushedFcn = createCallbackFcn(app, @EliminarButtonPushed, true);
            app.EliminarButton.Text = 'Eliminar';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Nombre'; 'Sensor'; 'Descripción'; 'Habilitar'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = false;
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WEB_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end